classdef Servo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ServoSetKnobLabel            matlab.ui.control.Label
        ServoSetKnob                 matlab.ui.control.Knob
        SerialPortListDropDownLabel  matlab.ui.control.Label
        SerialPortListDropDown       matlab.ui.control.DropDown
        SetPortButton                matlab.ui.control.Button
        ServoPos                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        myPort
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %get all available comports
            app.SerialPortListDropDown.Items = serialportlist("available");
            
        end

        % Value changed function: ServoSetKnob
        function ServoSetKnobValueChanged(app, event)
            val = uint8(round(app.ServoSetKnob.Value));
            write(app.myPort,val,"uint8");
            app.ServoPos.Text = string(val);
        end

        % Button pushed function: SetPortButton
        function SetPortButtonPushed(app, event)
            app.myPort = serialport(app.SerialPortListDropDown.Value,9600);
            app.SerialPortListDropDown.Items = serialportlist("available");%refresh
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ServoSetKnobLabel = uilabel(app.UIFigure);
            app.ServoSetKnobLabel.Text = 'ServoSet';
            app.ServoSetKnob = uiknob(app.UIFigure, 'continuous');
            app.ServoSetKnob.Limits = [0 180];
            app.ServoSetKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.ServoSetKnob.ValueChangedFcn = createCallbackFcn(app, @ServoSetKnobValueChanged, true);
            app.ServoSetKnob.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.SerialPortListDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortListDropDownLabel.Text = 'Serial Port List';
            app.SerialPortListDropDown = uidropdown(app.UIFigure);
            app.SerialPortListDropDown.Items = {'Option 3', 'Option 4'};
            app.SerialPortListDropDown.Value = 'Option 3';
            app.SetPortButton = uibutton(app.UIFigure, 'push');
            app.SetPortButton.ButtonPushedFcn = createCallbackFcn(app, @SetPortButtonPushed, true);
            app.SetPortButton.Tooltip = {'Set Port where your Arduino is connected'};
            app.SetPortButton.Text = 'SetPort';
            app.ServoPos = uilabel(app.UIFigure);
            app.ServoPos.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Servo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end